classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ENTERButton                 matlab.ui.control.Button
        Slider                      matlab.ui.control.Slider
        HowsimilardidthetwotexturesfeelLabel  matlab.ui.control.Label
        ArethefirstandsecondtextureoneandthesameLabel  matlab.ui.control.Label
        YESButton                   matlab.ui.control.Button
        TRIALLabel                  matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        trialbeginbutton            matlab.ui.control.Button
        TEXTUREBEINGDISPLAYEDLabel  matlab.ui.control.Label
        firsttextureLampLabel       matlab.ui.control.Label
        firsttexture                matlab.ui.control.Lamp
        secondtextureLampLabel      matlab.ui.control.Label
        secondtexture               matlab.ui.control.Lamp
        NOButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        entry1 = 0;
        entry2 = 0;
        trialbegin = 0;
        enter = 0;
        texturesame = -1;        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ENTERButton
        function ENTERButtonPushed(app, event)
            if (app.entry1*app.entry2 == 0)
                app.ENTERButton.Text = 'Please enter your response';
                app.ENTERButton.BackgroundColor = [1 0 0];
                pause(1);
                app.ENTERButton.Text = 'ENTER';
                app.ENTERButton.BackgroundColor = [.95 .95 .95];
            else
                app.ENTERButton.BackgroundColor = [0 1 0];
                app.enter = 1;
                app.entry1 = 0;
                app.entry2 = 0;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.entry2 = 1;
        end

        % Button pushed function: YESButton
        function YESButtonPushed(app, event)
            app.entry1 = 1;
            app.YESButton.BackgroundColor = [0 1 0];
            app.NOButton.BackgroundColor = [.95 .95 .95];
            app.texturesame = 1;
        end

        % Button pushed function: NOButton
        function NOButtonPushed(app, event)
            app.entry1 = 1;
            app.YESButton.BackgroundColor = [.95 .95 .95];
            app.NOButton.BackgroundColor = [0 1 0];
            app.texturesame = 0;
        end

        % Button pushed function: trialbeginbutton
        function trialbeginbuttonButtonPushed(app, event)
            app.trialbegin = 1;
            app.trialbeginbutton.BackgroundColor = [0 1 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ENTERButton = uibutton(app.UIFigure, 'push');
            app.ENTERButton.ButtonPushedFcn = createCallbackFcn(app, @ENTERButtonPushed, true);
            app.ENTERButton.Text = 'ENTER';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.HowsimilardidthetwotexturesfeelLabel = uilabel(app.UIFigure);
            app.HowsimilardidthetwotexturesfeelLabel.Text = 'How similar did the two textures feel? (0 not similar at all, 10 very similar)';
            app.ArethefirstandsecondtextureoneandthesameLabel = uilabel(app.UIFigure);
            app.ArethefirstandsecondtextureoneandthesameLabel.Text = 'Are the first and second texture one and the same?';
            app.YESButton = uibutton(app.UIFigure, 'push');
            app.YESButton.ButtonPushedFcn = createCallbackFcn(app, @YESButtonPushed, true);
            app.YESButton.Text = 'YES';
            app.NOButton = uibutton(app.UIFigure, 'push');
            app.NOButton.ButtonPushedFcn = createCallbackFcn(app, @NOButtonPushed, true);
            app.NOButton.Text = 'NO';
            app.TRIALLabel = uilabel(app.UIFigure);
            app.TRIALLabel.Text = 'TRIAL';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '1/45';
            app.trialbeginbutton = uibutton(app.UIFigure, 'push');
            app.trialbeginbutton.ButtonPushedFcn = createCallbackFcn(app, @trialbeginbuttonButtonPushed, true);
            app.trialbeginbutton.Text = 'BEGIN TRIAL';
            app.TEXTUREBEINGDISPLAYEDLabel = uilabel(app.UIFigure);
            app.TEXTUREBEINGDISPLAYEDLabel.Text = 'TEXTURE BEING DISPLAYED: ';
            app.firsttextureLampLabel = uilabel(app.UIFigure);
            app.firsttextureLampLabel.Text = 'first texture ';
            app.firsttexture = uilamp(app.UIFigure);
            app.secondtextureLampLabel = uilabel(app.UIFigure);
            app.secondtextureLampLabel.Text = 'second texture';
            app.secondtexture = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end