classdef exp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PresswhenreadyButton       matlab.ui.control.Button
        GAINSliderLabel            matlab.ui.control.Label
        GAINSlider                 matlab.ui.control.Slider
        Button_1                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        Button_7                   matlab.ui.control.Button
        selectatexturenumberLabel  matlab.ui.control.Label
        EditField_1                matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        enter = 0; % Description
        ready = 1;
        gain = 1;
        tex = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PresswhenreadyButton
        function PresswhenreadyButtonPushed(app, event)
            if (app.ready == 1)
                app.enter = 1;
                app.PresswhenreadyButton.BackgroundColor = [1 0 0];
                app.PresswhenreadyButton.Text = 'Please wait';
                app.ready = 0;
            else
                app.PresswhenreadyButton.BackgroundColor = [1 0 0];
                app.PresswhenreadyButton.Text = 'Not ready just yet!';
                pause(1)
                app.PresswhenreadyButton.Text = 'Please wait';
            end
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.Button_1.BackgroundColor = [0 1 0];
            app.Button_2.BackgroundColor = [1 1 1]*.96;
            app.Button_3.BackgroundColor = [1 1 1]*.96;
            app.Button_4.BackgroundColor = [1 1 1]*.96;
            app.Button_5.BackgroundColor = [1 1 1]*.96;
            app.Button_6.BackgroundColor = [1 1 1]*.96;
            app.Button_7.BackgroundColor = [1 1 1]*.96;      
            app.tex = 1;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.BackgroundColor = [0 1 0];
            app.Button_1.BackgroundColor = [1 1 1]*.96;
            app.Button_3.BackgroundColor = [1 1 1]*.96;
            app.Button_4.BackgroundColor = [1 1 1]*.96;
            app.Button_5.BackgroundColor = [1 1 1]*.96;
            app.Button_6.BackgroundColor = [1 1 1]*.96;
            app.Button_7.BackgroundColor = [1 1 1]*.96;
            app.tex = 2;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.BackgroundColor = [0 1 0];
            app.Button_1.BackgroundColor = [1 1 1]*.96;
            app.Button_2.BackgroundColor = [1 1 1]*.96;
            app.Button_4.BackgroundColor = [1 1 1]*.96;
            app.Button_5.BackgroundColor = [1 1 1]*.96;
            app.Button_6.BackgroundColor = [1 1 1]*.96;
            app.Button_7.BackgroundColor = [1 1 1]*.96; 
            app.tex = 3;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Button_4.BackgroundColor = [0 1 0];
            app.Button_1.BackgroundColor = [1 1 1]*.96;
            app.Button_2.BackgroundColor = [1 1 1]*.96;
            app.Button_3.BackgroundColor = [1 1 1]*.96;
            app.Button_5.BackgroundColor = [1 1 1]*.96;
            app.Button_6.BackgroundColor = [1 1 1]*.96;
            app.Button_7.BackgroundColor = [1 1 1]*.96; 
            app.tex = 4;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Button_5.BackgroundColor = [0 1 0];
            app.Button_1.BackgroundColor = [1 1 1]*.96;
            app.Button_2.BackgroundColor = [1 1 1]*.96;
            app.Button_3.BackgroundColor = [1 1 1]*.96;
            app.Button_4.BackgroundColor = [1 1 1]*.96;
            app.Button_6.BackgroundColor = [1 1 1]*.96;
            app.Button_7.BackgroundColor = [1 1 1]*.96; 
            app.tex = 5;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Button_6.BackgroundColor = [0 1 0];
            app.Button_1.BackgroundColor = [1 1 1]*.96;
            app.Button_2.BackgroundColor = [1 1 1]*.96;
            app.Button_3.BackgroundColor = [1 1 1]*.96;
            app.Button_4.BackgroundColor = [1 1 1]*.96;
            app.Button_5.BackgroundColor = [1 1 1]*.96;
            app.Button_7.BackgroundColor = [1 1 1]*.96;
            app.tex = 6;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Button_7.BackgroundColor = [0 1 0];
            app.Button_1.BackgroundColor = [1 1 1]*.96;
            app.Button_2.BackgroundColor = [1 1 1]*.96;
            app.Button_3.BackgroundColor = [1 1 1]*.96;
            app.Button_4.BackgroundColor = [1 1 1]*.96;
            app.Button_5.BackgroundColor = [1 1 1]*.96;
            app.Button_6.BackgroundColor = [1 1 1]*.96; 
            app.tex = 7;
        end

        % Value changed function: GAINSlider
        function GAINSliderValueChanged(app, event)
            app.gain = app.GAINSlider.Value;
            app.EditField_1.Value = app.gain;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PresswhenreadyButton = uibutton(app.UIFigure, 'push');
            app.PresswhenreadyButton.ButtonPushedFcn = createCallbackFcn(app, @PresswhenreadyButtonPushed, true);
            app.PresswhenreadyButton.Text = {'Press when ready'; ''};
            app.GAINSliderLabel = uilabel(app.UIFigure);
            app.GAINSliderLabel.Text = 'GAIN';
            app.GAINSlider = uislider(app.UIFigure);
            app.GAINSlider.Limits = [0 6];
            app.GAINSlider.MajorTicks = [0 1 2 3 4 5 6];
            app.GAINSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', ''};
            app.GAINSlider.ValueChangedFcn = createCallbackFcn(app, @GAINSliderValueChanged, true);
            app.GAINSlider.Value = 1;
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '5';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '6';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '7';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '4';
            app.EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_1.ValueDisplayFormat = '%1.2g';
            app.EditField_1.Value = 1;
            app.selectatexturenumberLabel = uilabel(app.UIFigure);
            app.selectatexturenumberLabel.Text = 'select a texture number:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end