classdef exp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PresstoSwipeButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        enter = 0; % Description
        ready = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PresstoSwipeButton
        function PresstoSwipeButtonPushed(app, event)
            if (app.ready == 1)
                app.enter = 1;
                app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                app.PresstoSwipeButton.Text = 'Swipe in Progress';
                app.ready = 0;
            else
                app.PresstoSwipeButton.BackgroundColor = [1 0 0];
                app.PresstoSwipeButton.Text = 'Please wait';
                pause(1)
                app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                app.PresstoSwipeButton.Text = 'Swipe in Progress';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PresstoSwipeButton = uibutton(app.UIFigure, 'push');
            app.PresstoSwipeButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoSwipeButtonPushed, true);
            app.PresstoSwipeButton.Text = 'Press to Swipe';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end