classdef exp3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GAINSlider               matlab.ui.control.Slider
        TEXTUREVOLUMELabel       matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        FinishedAdjustingButton  matlab.ui.control.Button
        PresstoSwipeButton       matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        VolumeSliderLabel        matlab.ui.control.Label
        tex                      matlab.ui.control.Label
    end

    
    properties (Access = public)
        again = 0;
        next = 0;
        gain = -1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GAINSlider
        function GAINSliderValueChanged(app, event)
            app.gain = app.GAINSlider.Value;
            app.EditField.Value = app.gain;       
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.gain = 1;
            app.next = 0;
            app.again = 0;
            app.GAINSlider.Value = app.gain;
            app.EditField.Value = app.gain;
            app.FinishedAdjustingButton.BackgroundColor = [.96 .96 .96];
            app.PresstoSwipeButton.BackgroundColor = [.96 .96 .96];
            app.PresstoSwipeButton.Text =  'Swipe Again';
            app.FinishedAdjustingButton.Text = 'Finished Adjusting';
        end

        % Button pushed function: FinishedAdjustingButton
        function FinishedAdjustingButtonPushed(app, event)
            if (app.gain == -1)
                    app.FinishedAdjustingButton.Text = 'Please Select a Volume';
                    app.FinishedAdjustingButton.BackgroundColor = [1 .5 .5];
                    pause(1)
                    app.FinishedAdjustingButton.Text = 'Finished Adjusting';
                    app.FinishedAdjustingButton.BackgroundColor = [.96 .96 .96];
            else
                if (app.next == 0)
                    app.FinishedAdjustingButton.Text = 'Press to Undo';
                    app.FinishedAdjustingButton.BackgroundColor = [.5 .5 1];
                    app.PresstoSwipeButton.Text =  'Load Next Texture';
                    app.PresstoSwipeButton.BackgroundColor = [.96 .96 .96];
                    app.next = 1;
                else
                    if app.again == 1
                        app.FinishedAdjustingButton.BackgroundColor = [1 .5 .5];
                        app.FinishedAdjustingButton.Text = 'Please Wait!';
                        pause(1)
                        app.FinishedAdjustingButton.BackgroundColor = [.5 .5 1];
                        app.FinishedAdjustingButton.Text = 'Loading A Texture';
                    else
                        app.FinishedAdjustingButton.BackgroundColor = [.96 .96 .96];
                        app.FinishedAdjustingButton.Text = 'Finished Adjusting';
                        app.next = 0;
                        app.PresstoSwipeButton.Text =  'Swipe Again';
                        app.PresstoSwipeButton.BackgroundColor = [.96 .96 .96];
                    end
                end
            end
        end

        % Button pushed function: PresstoSwipeButton
        function PresstoSwipeButtonPushed(app, event)
           if (app.again == 0)
                if app.next == 1
                   app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                   app.PresstoSwipeButton.Text =  'Loading A Texture';
                   app.FinishedAdjustingButton.BackgroundColor = [.5 .5 1];
                   app.FinishedAdjustingButton.Text = 'Loading A Texture';                   
                else
                   app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                   app.PresstoSwipeButton.Text =  'Swipe in progress';                   
                end
                app.again = 1;
           else               
                app.PresstoSwipeButton.BackgroundColor = [1 .5 .5];
                app.PresstoSwipeButton.Text = 'Please Wait!';
                pause(1)                
                if app.next == 1
                    app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                    app.PresstoSwipeButton.Text = 'Loading A Texture';
                else
                    app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                    app.PresstoSwipeButton.Text = 'Swipe in Progress';
                end        
            end
        end

        % Value changing function: GAINSlider
        function GAINSliderValueChanging(app, event)
            app.gain = event.Value;
            app.EditField.Value = app.gain;    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GAINSlider = uislider(app.UIFigure);
            app.GAINSlider.Limits = [-4 4];
            app.GAINSlider.MajorTicks = [];
            app.GAINSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', ''};
            app.GAINSlider.ValueChangedFcn = createCallbackFcn(app, @GAINSliderValueChanged, true);
            app.GAINSlider.ValueChangingFcn = createCallbackFcn(app, @GAINSliderValueChanging, true);
            app.TEXTUREVOLUMELabel = uilabel(app.UIFigure);
            app.TEXTUREVOLUMELabel.Text = 'TEXTURE VOLUME';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%1.2g';
            app.FinishedAdjustingButton = uibutton(app.UIFigure, 'push');
            app.FinishedAdjustingButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedAdjustingButtonPushed, true);
            app.FinishedAdjustingButton.Text = {'Finished Adjusting'; ''};
            app.PresstoSwipeButton = uibutton(app.UIFigure, 'push');
            app.PresstoSwipeButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoSwipeButtonPushed, true);
            app.PresstoSwipeButton.Text = 'Press to Swipe';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume Slider';
            app.tex = uilabel(app.UIFigure);
            app.tex.Text = {'Presenting Texture 1'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end