classdef exp4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SIMSlider                matlab.ui.control.Slider
        TEXTUREVOLUMELabel       matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        FinishedAdjustingButton  matlab.ui.control.Button
        PresstoSwipeButton       matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        SimilaritySliderLabel    matlab.ui.control.Label
        tex                      matlab.ui.control.Label
        SLIDINGLEFTLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        again = 0;
        next = 0;
        similarity = -1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SIMSlider
        function SIMSliderValueChanged(app, event)
            app.similarity = app.SIMSlider.Value;
            app.EditField.Value = app.similarity;       
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.similarity = -1;
            app.next = 0;
            app.again = 0;
            app.SIMSlider.Value = app.similarity;
            app.EditField.Value = app.similarity;
            app.FinishedAdjustingButton.BackgroundColor = [.96 .96 .96];
            app.PresstoSwipeButton.BackgroundColor = [.96 .96 .96];
            app.PresstoSwipeButton.Text =  'Swipe Again';
            app.FinishedAdjustingButton.Text = 'Finished Adjusting';
        end

        % Button pushed function: FinishedAdjustingButton
        function FinishedAdjustingButtonPushed(app, event)
            if app.similarity == -1
                    app.FinishedAdjustingButton.Text = 'Enter a Similarity';
                    app.FinishedAdjustingButton.BackgroundColor = [1 .5 .5];
                    pause(1)
                    app.FinishedAdjustingButton.BackgroundColor = [.96 .96 .96];
                    app.FinishedAdjustingButton.Text = 'Finished Adjusting';
            else                
                if (app.next == 0)
                    app.FinishedAdjustingButton.Text = 'Press to Undo';
                    app.FinishedAdjustingButton.BackgroundColor = [.5 .5 1];
                    app.PresstoSwipeButton.Text =  'Load Next Texture';
                    app.PresstoSwipeButton.BackgroundColor = [.96 .96 .96];
                    app.next = 1;
                else
                    if app.again == 1
                        app.FinishedAdjustingButton.BackgroundColor = [1 .5 .5];
                        app.FinishedAdjustingButton.Text = 'Please Wait!';
                        pause(1)
                        app.FinishedAdjustingButton.BackgroundColor = [.5 .5 1];
                        app.FinishedAdjustingButton.Text = 'Loading A Texture';
                    else
                        app.FinishedAdjustingButton.BackgroundColor = [.96 .96 .96];
                        app.FinishedAdjustingButton.Text = 'Finished Adjusting';
                        app.next = 0;
                        app.PresstoSwipeButton.Text =  'Swipe Again';
                        app.PresstoSwipeButton.BackgroundColor = [.96 .96 .96];
                    end
                end
            end
        end

        % Button pushed function: PresstoSwipeButton
        function PresstoSwipeButtonPushed(app, event)
           if (app.again == 0)
                if app.next == 1
                   app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                   app.PresstoSwipeButton.Text =  'Loading A Texture';
                   app.FinishedAdjustingButton.BackgroundColor = [.5 .5 1];
                   app.FinishedAdjustingButton.Text = 'Loading A Texture';                   
                else
                   app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                   app.PresstoSwipeButton.Text =  'Swipe in progress';                   
                end
                app.again = 1;
           else               
                app.PresstoSwipeButton.BackgroundColor = [1 .5 .5];
                app.PresstoSwipeButton.Text = 'Please Wait!';
                pause(1)                
                if app.next == 1
                    app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                    app.PresstoSwipeButton.Text = 'Loading A Texture';
                else
                    app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                    app.PresstoSwipeButton.Text = 'Swipe in Progress';
                end        
            end
        end

        % Value changing function: SIMSlider
        function SIMSliderValueChanging(app, event)
            app.similarity = event.Value;
            app.EditField.Value = app.similarity;    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SIMSlider = uislider(app.UIFigure);
            app.SIMSlider.Limits = [0 1];
            app.SIMSlider.MajorTicks = [];
            app.SIMSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', ''};
            app.SIMSlider.ValueChangedFcn = createCallbackFcn(app, @SIMSliderValueChanged, true);
            app.SIMSlider.ValueChangingFcn = createCallbackFcn(app, @SIMSliderValueChanging, true);
            app.TEXTUREVOLUMELabel = uilabel(app.UIFigure);
            app.TEXTUREVOLUMELabel.Visible = 'off';
            app.TEXTUREVOLUMELabel.Text = 'TEXTURE VOLUME';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%1.2g';
            app.EditField.Visible = 'off';
            app.EditField.Value = 1;
            app.FinishedAdjustingButton = uibutton(app.UIFigure, 'push');
            app.FinishedAdjustingButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedAdjustingButtonPushed, true);
            app.FinishedAdjustingButton.Text = {'Finished Adjusting'; ''};
            app.PresstoSwipeButton = uibutton(app.UIFigure, 'push');
            app.PresstoSwipeButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoSwipeButtonPushed, true);
            app.PresstoSwipeButton.Text = 'Press to Swipe';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.SimilaritySliderLabel = uilabel(app.UIFigure);
            app.SimilaritySliderLabel.Text = 'Similarity Slider';
            app.tex = uilabel(app.UIFigure);
            app.tex.Text = {'Presenting Texture Combination 1/21'; ''};
            app.SLIDINGLEFTLabel = uilabel(app.UIFigure);
            app.SLIDINGLEFTLabel.Visible = 'off';
            app.SLIDINGLEFTLabel.Text = 'SLIDING LEFT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end