classdef exp6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TEXTUREVOLUMELabel       matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        FinishedAnsweringButton  matlab.ui.control.Button
        PresstoSwipeButton       matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        LEFTcomes2ndButton       matlab.ui.control.Button
        RIGHTcomes1stButton      matlab.ui.control.Button
        tex                      matlab.ui.control.Label
        SLIDINGLEFTLabel         matlab.ui.control.Label
        tex_2                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        again = 0;
        next = 0;
        answer = 2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SIMSliderValueChanged(app, event)
            app.similarity = app.SIMSlider.Value;
            app.EditField.Value = app.similarity;       
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.answer = -1;
            app.next = 0;
            app.again = 0;
            app.SIMSlider.Value = app.similarity;
            app.EditField.Value = app.similarity;
            app.FinishedAnsweringButton.BackgroundColor = [.96 .96 .96];
            app.PresstoSwipeButton.BackgroundColor = [.96 .96 .96];
            app.LEFTcomes2ndButton.BackgroundColor = [.96 .96 .96];
            app.RIGHTcomes2ndButton.BackgroundColor = [.96 .96 .96];
            app.PresstoSwipeButton.Text =  'Swipe Again';
            app.FinishedAnsweringButton.Text = 'Finished Adjusting';
            
        end

        % Button pushed function: FinishedAnsweringButton
        function FinishedAnsweringButtonPushed(app, event)
            if app.answer == 2
                app.FinishedAnsweringButton.Text = 'Select an Answer';
                app.FinishedAnsweringButton.BackgroundColor = [1 .5 .5];
                pause(1)
                app.FinishedAnsweringButton.BackgroundColor = [.96 .96 .96];
                app.FinishedAnsweringButton.Text = 'Finished Adjusting';
            else                
                if (app.next == 0)
                    app.FinishedAnsweringButton.Text = 'Press to Undo';
                    app.FinishedAnsweringButton.BackgroundColor = [.5 .5 1];
                    app.PresstoSwipeButton.Text =  'Load Next Texture';
                    app.PresstoSwipeButton.BackgroundColor = [.96 .96 .96];
                    app.next = 1;
                else
                    if app.again == 1
                        app.FinishedAnsweringButton.BackgroundColor = [1 .5 .5];
                        app.FinishedAnsweringButton.Text = 'Please Wait!';
                        pause(1)
                        app.FinishedAnsweringButton.BackgroundColor = [.5 .5 1];
                        app.FinishedAnsweringButton.Text = 'Loading A Texture';
                    else
                        app.FinishedAnsweringButton.BackgroundColor = [.96 .96 .96];
                        app.FinishedAnsweringButton.Text = 'Finished Adjusting';
                        app.next = 0;
                        app.PresstoSwipeButton.Text =  'Swipe Again';
                        app.PresstoSwipeButton.BackgroundColor = [.96 .96 .96];
                    end
                end
            end
        end

        % Button pushed function: PresstoSwipeButton
        function PresstoSwipeButtonPushed(app, event)
           if (app.again == 0)
                if app.next == 1
                   app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                   app.PresstoSwipeButton.Text =  'Loading A Texture';
                   app.FinishedAnsweringButton.BackgroundColor = [.5 .5 1];
                   app.FinishedAnsweringButton.Text = 'Loading A Texture';                   
                else
                   app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                   app.PresstoSwipeButton.Text =  'Swipe in progress';    
                end      
                app.again = 1;
           else               
                app.PresstoSwipeButton.BackgroundColor = [1 .5 .5];
                app.PresstoSwipeButton.Text = 'Please Wait!';
                pause(1)                
                if app.next == 1
                    app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                    app.PresstoSwipeButton.Text = 'Loading A Texture';
                else
                    app.PresstoSwipeButton.BackgroundColor = [.5 .5 1];
                    app.PresstoSwipeButton.Text = 'Swipe in Progress';
                end        
            end
        end

        % Callback function
        function SIMSliderValueChanging(app, event)
            app.similarity = event.Value;
            app.EditField.Value = app.similarity;    
        end

        % Button pushed function: LEFTcomes2ndButton
        function LEFTcomes2ndButtonPushed(app, event)
            app.answer = 1;  
            app.LEFTcomes2ndButton.BackgroundColor = [.5 .5 1];
            app.RIGHTcomes1stButton.BackgroundColor = [.96 .96 .96];
        end

        % Button pushed function: RIGHTcomes1stButton
        function RIGHTcomes1stButtonPushed(app, event)
            app.answer = 0;
            app.LEFTcomes2ndButton.BackgroundColor = [.96 .96 .96];
            app.RIGHTcomes1stButton.BackgroundColor = [.5 .5 1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TEXTUREVOLUMELabel = uilabel(app.UIFigure);
            app.TEXTUREVOLUMELabel.Visible = 'off';
            app.TEXTUREVOLUMELabel.Text = 'TEXTURE VOLUME';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%1.2g';
            app.EditField.Visible = 'off';
            app.EditField.Value = 1;
            app.FinishedAnsweringButton = uibutton(app.UIFigure, 'push');
            app.FinishedAnsweringButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedAnsweringButtonPushed, true);
            app.FinishedAnsweringButton.Text = {'Finished Answering'; ''};
            app.PresstoSwipeButton = uibutton(app.UIFigure, 'push');
            app.PresstoSwipeButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoSwipeButtonPushed, true);
            app.PresstoSwipeButton.Text = 'Press to Swipe';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.tex = uilabel(app.UIFigure);
            app.tex.Text = 'Presenting Texture Combination 1/49';
            app.SLIDINGLEFTLabel = uilabel(app.UIFigure);
            app.SLIDINGLEFTLabel.Visible = 'off';
            app.SLIDINGLEFTLabel.Text = 'SLIDING LEFT';
            app.LEFTcomes2ndButton = uibutton(app.UIFigure, 'push');
            app.LEFTcomes2ndButton.ButtonPushedFcn = createCallbackFcn(app, @LEFTcomes2ndButtonPushed, true);
            app.LEFTcomes2ndButton.Text = 'LEFT (comes 2nd)';
            app.RIGHTcomes1stButton = uibutton(app.UIFigure, 'push');
            app.RIGHTcomes1stButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHTcomes1stButtonPushed, true);
            app.RIGHTcomes1stButton.Text = 'RIGHT (comes 1st)';
            app.tex_2 = uilabel(app.UIFigure);
            app.tex_2.Text = 'Which texture is most similar to one in the middle?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end